classdef UL_Testing_App_thermal_derating < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MainTab                    matlab.ui.container.Tab
        ActionLogsPanel            matlab.ui.container.Panel
        LogScreen                  matlab.ui.control.TextArea
        ReportingPanel             matlab.ui.container.Panel
        GenerateReportButton       matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        DOCXCheckBox               matlab.ui.control.CheckBox
        PDFCheckBox                matlab.ui.control.CheckBox
        AddressEditField           matlab.ui.control.EditField
        AddressEditFieldLabel      matlab.ui.control.Label
        TestingFlowControlPanel    matlab.ui.container.Panel
        RunButton                  matlab.ui.control.Button
        StatusTabPanel             matlab.ui.container.Panel
        IPEditField                matlab.ui.control.EditField
        IPEditFieldLabel           matlab.ui.control.Label
        InverterLabel              matlab.ui.control.Label
        Inverter_button            matlab.ui.control.Button
        CommPort                   matlab.ui.control.DropDown
        PortDropDownLabel          matlab.ui.control.Label
        GridSimulatorLabel         matlab.ui.control.Label
        gridSim_button             matlab.ui.control.Button
        PowerQualityAnalyzerLabel  matlab.ui.control.Label
        PQA_button                 matlab.ui.control.Button
        TestingProgramsPanel       matlab.ui.container.Panel
        LoadTestsButton            matlab.ui.control.Button
        AboutTab                   matlab.ui.container.Tab
        DisclaimerLabel            matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ThisapplicationisdevelopedfortestingULinverterLabel  matlab.ui.control.Label
        DescriptionLabel           matlab.ui.control.Label
        SajidAlisajidaliskyelectriccomLabel  matlab.ui.control.Label
        AuthorLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
       inverterClient        WebSocketClient   %class file that is needed to establish connection with inverter
        testListCheckboxes       matlab.ui.control.CheckBox  % Description
    end
    
    properties (Access = public)
        PQA_Status % Description
        gridSim_commPort % Description
        gridSim_Status % Description
        
        inverterObj % Description
        inverterIP % Description
        inverter_status % Description
        ReportAddress % Description
        docxSelect % Description
        pdfSelect % Description
        test % Description
        testsDirectory % Description
        PQAObj             PQAConnection     % Class file that is needed to establishconnection with PQA
        gridSimObj         GridSimulator     % class file that is needed to establish connection with gridSimulator
        
    end
    
    methods (Access = public)
        
        
        function loadTests(app)
            
            % Get a list of all .mlx files in the folder
            app.testsDirectory = uigetdir('.m');
            mFiles = dir(fullfile(app.testsDirectory, '*.m'));
        
            % Clear any existing checkboxes
            delete(app.TestingProgramsPanel.Children);
        
            % Create checkboxes for each file
            numFiles = length(mFiles);
            app.testListCheckboxes = matlab.ui.control.CheckBox.empty(numFiles, 0);
            checkboxHeight = 30;
            spacing = 5;
            panelHeight = app.TestingProgramsPanel.Position(4);
            % Adjust the startY to add an additional line space at the top
            startY = panelHeight - checkboxHeight - spacing - (checkboxHeight + spacing);
            
            for i = 1:numFiles
                app.testListCheckboxes(i) = uicheckbox(app.TestingProgramsPanel, 'Text', mFiles(i).name, ...
                                               'Position', [10 startY-(checkboxHeight+spacing)*(i-1) 180 checkboxHeight],'WordWrap','on');
            
                 % Enable word wrapping
                app.testListCheckboxes(i).WordWrap = 'on';
                app.TestingProgramsPanel.Scrollable = "on";
        

            end

        end
        
        function runSelectedTests(app)
        % Loop through checkboxes and run selected scripts
            for i = 1:length(app.testListCheckboxes)
                if app.testListCheckboxes(i).Value
                    scriptName = app.testListCheckboxes(i).Text;
                    [~, funcName, ext] = fileparts(scriptName);
                    
                    if strcmp(ext, '.m') % Ensure it's an .m file
                        %scriptPath = fullfile(app.testsDirectory, scriptName);
                        
                        try
                            % Add the folder to the path temporarily
                            addpath(app.testsDirectory);
                            % Call the function dynamically
                            feval(funcName, app);
                            % Remove the folder from the path
                            %rmpath(app.testsDirectory);

                        catch ME
                            fprintf('Error running test %s: %s\n', funcName, ME.message);
                        end
                    else
                        fprintf('Skipping non-.m file: %s\n', scriptName);
                    end
                end
            end
        end


        function gridSimDefaultSettings(app)
            app.LogScreen.Value{end+1} = 'Setting grid simulator to default settings';
            app.LogScreen.Value{end+1} = 'Setting grid Simulator to (U,V,W,Freq) 120V 120V 0V 60Hz';
            app.gridSimObj.setParameters(120,120,0,60);  
            app.LogScreen.Value{end+1} = 'Done';
        end
        
        function inverterDefaultSettings(app)
            app.LogScreen.Value{end+1} = 'Setting the inverter to default settings';
            app.LogScreen.Value{end+1} =  'Turning inverter control off.';
            app.inverterObj.send('control_off');
            pause(1);
            app.LogScreen.Value{end+1} =  'Setting inverter OV Trip Limit to 132 volts.';
            app.inverterObj.send('{"gridOvLimit":132}')
            pause(1);
            app.LogScreen.Value{end+1} =  'Setting inverter under Frequency Limit to 58.5Hz.';
            app.inverterObj.send('{"gridUfLimit":58.5}');
            pause(1);
            app.LogScreen.Value{end+1} =  'Setting inverter Reconnect block timer to 300 seconds.';
            app.inverterObj.send('{"reconnectBlockTimer":300}');
            pause(1);            
        end
        
        function logOnScreen(app,content)
            time_stamp=datestr(now,'yyyy-mm-dd HH:MM:SS');
            if(ischar(content))
                app.LogScreen.Value{end+1} = ['[ ' time_stamp ' ] ' content];
            else
                app.LogScreen.Value{end+1} = ['[ ' time_stamp ' ] ' char(content)];  
            end
            scroll(app.LogScreen, 'bottom');
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %start app centered on the screen
            % Calculate centered position on the screen
            screenSize = get(0, 'ScreenSize');  % Get the screen size
            windowWidth = app.UIFigure.Position(3);
            windowHeight = app.UIFigure.Position(4);
            xPos = (screenSize(3) - windowWidth) / 2;  % Calculate x position
            yPos = (screenSize(4) - windowHeight) / 2;  % Calculate y position
            
            % Set the centered position
            app.UIFigure.Position = [xPos, yPos, windowWidth, windowHeight];
            app.UIFigure.WindowStyle = "alwaysontop";
            app.UIFigure.WindowStyle="normal";

            
            app.PQAObj = PQAConnection();
            app.gridSimObj = GridSimulator(); 
            app.gridSim_Status = false;
            app.inverter_status = false;
            app.PQA_Status = false;
            app.PQA_button.Text = "Connect";
            app.inverterIP = '10.0.0.1';
            app.IPEditField.Value = app.inverterIP;


            %create default address to store reports
            app.ReportAddress = [ getenv('USERPROFILE') '\Documents\MATLAB'];
            app.AddressEditField.Value = app.ReportAddress;
            app.DOCXCheckBox.Value = true;
            app.docxSelect = true;
            app.PDFCheckBox.Value = false;
            app.pdfSelect = false;
            
            
        end

        % Button pushed function: PQA_button
        function PQA_buttonButtonPushed(app, event)
            %break
        if(isempty(app.PQA_Status) || ~app.PQA_Status)
         %establish connection
         app.PQA_Status= app.PQAObj.connect();
         app.PQA_button.Text = "Disconnect";
         %rename the button to disconnect
         %change the color of button to green
         app.PQA_button.BackgroundColor = 'g';
        else
            %disconnect
            app.PQAObj.disconnect();
            %app.PQA_Status
            app.PQA_Status=false;

            %rename the button to connect
            app.PQA_button.Text = "Connect";
            %change the color of the button to connect
            app.PQA_button.BackgroundColor = 'r';
        end

        end

        % Drop down opening function: CommPort
        function CommPortOpening(app, event)
             commPorts=serialportlist();
            % Set the items of the dropdown to be the available COM ports
            app.CommPort.Items = commPorts;
            
            % Check if there are available ports
            if isempty(app.gridSim_commPort) && ~isempty(commPorts)
                % Set the selected port to the first available port
                app.gridSim_commPort = commPorts{1};
            else
                % If no ports are available, set the selected port to empty
                app.gridSim_commPort = '';
            end
            
        end

        % Button pushed function: gridSim_button
        function gridSim_buttonButtonPushed(app, event)
            if(isempty(app.gridSim_Status) || ~app.gridSim_Status)
                %establish connection
                baudRate = 38400;
                app.gridSimObj.connect(app.gridSim_commPort,baudRate);
                %rename buttonn
                app.gridSim_button.Text = "Disconnect";
                %set background color to green
                app.gridSim_button.BackgroundColor = 'g';
                %app.gridSim_Status = true;
                app.gridSim_Status = true;
            else
                %disconnect
                app.gridSimObj.disconnect();

                %update status
                app.gridSim_Status = false;
                %rename button
                app.gridSim_button.Text = "Connect";

                %set background color to red
                app.gridSim_button.BackgroundColor = 'r';
            end
            app.gridSimObj.setParameters(121,121,0,60);
        end

        % Value changed function: CommPort
        function CommPortValueChanged(app, event)
            app.gridSim_commPort = string(app.CommPort.Value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.runSelectedTests();
           

        end

        % Button pushed function: Inverter_button
        function Inverter_buttonButtonPushed(app, event)
            %IP='10.0.0.1';             
            IP=char(app.inverterIP);
            address=['ws://' IP ':3000'];
            
            
            if(~app.inverter_status)
                app.inverterObj = WebSocketClient(address);
                app.Inverter_button.Text = "Disconnect";
                app.Inverter_button.BackgroundColor = 'g';
                app.inverter_status = true;
            else
                app.inverterObj.close();
                app.Inverter_button.Text ="Connect";
                app.Inverter_button.BackgroundColor = 'r';
                app.inverter_status = false;
                delete(app.inverterObj);
            end
            
        end

        % Value changed function: IPEditField
        function IPEditFieldValueChanged(app, event)
            app.inverterIP  = app.IPEditField.Value;
           
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ReportAddress = uigetdir();
            app.AddressEditField.Value = app.ReportAddress;
            app.UIFigure.WindowStyle = "alwaysontop";
            app.UIFigure.WindowStyle = "normal";
        end

        % Value changed function: DOCXCheckBox
        function DOCXCheckBoxValueChanged(app, event)
            app.docxSelect = app.DOCXCheckBox.Value;
            if(app.docxSelect)
                app.pdfSelect = false;
                app.PDFCheckBox.Value =false;
            else
                app.pdfSelect = true;
                app.PDFCheckBox.Value = true;
            end
            
        end

        % Value changed function: PDFCheckBox
        function PDFCheckBoxValueChanged(app, event)
            app.pdfSelect = app.PDFCheckBox.Value;
            if(app.pdfSelect)
                app.docxSelect = false;
                app.DOCXCheckBox.Value = false;
            else
                app.docxSelect = true;
                app.DOCXCheckBox.Value = true;
            end
        end

        % Callback function
        function TreeSelectionChanged(app, event)

        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)

        end

        % Button pushed function: LoadTestsButton
        function LoadTestsButtonPushed(app, event)
            app.UIFigure.WindowStyle = "normal";
            app.loadTests();
            app.UIFigure.WindowStyle="alwaysontop";
            app.UIFigure.WindowStyle="normal";

        end

        % Value changed function: LogScreen
        function LogScreenValueChanged(app, event)
            value = app.LogScreen.Value;
            disp(value)
            %app.LogScreen.scroll = 'bottom';
%             scroll(logScreen,'bottom')
            disp('value changed')
        end

        % Callback function
        function LogScreenValueChanging(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.LoadTestsButton = uibutton(app.MainTab, 'push');
            app.LoadTestsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestsButtonPushed, true);
            app.LoadTestsButton.Text = 'Load Tests';
            app.TestingProgramsPanel = uipanel(app.MainTab);
            app.TestingProgramsPanel.Title = 'Testing Programs';
            app.StatusTabPanel = uipanel(app.MainTab);
            app.StatusTabPanel.Title = 'Status Tab';
            app.PQA_button = uibutton(app.StatusTabPanel, 'push');
            app.PQA_button.ButtonPushedFcn = createCallbackFcn(app, @PQA_buttonButtonPushed, true);
            app.PQA_button.Text = 'Connect';
            app.PowerQualityAnalyzerLabel = uilabel(app.StatusTabPanel);
            app.PowerQualityAnalyzerLabel.Text = 'Power Quality Analyzer';
            app.gridSim_button = uibutton(app.StatusTabPanel, 'push');
            app.gridSim_button.ButtonPushedFcn = createCallbackFcn(app, @gridSim_buttonButtonPushed, true);
            app.gridSim_button.Text = 'Connect';
            app.GridSimulatorLabel = uilabel(app.StatusTabPanel);
            app.GridSimulatorLabel.Text = 'Grid Simulator';
            app.PortDropDownLabel = uilabel(app.StatusTabPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.CommPort = uidropdown(app.StatusTabPanel);
            app.CommPort.Items = {'COM0'};
            app.CommPort.DropDownOpeningFcn = createCallbackFcn(app, @CommPortOpening, true);
            app.CommPort.ValueChangedFcn = createCallbackFcn(app, @CommPortValueChanged, true);
            app.CommPort.Value = 'COM0';
            app.Inverter_button = uibutton(app.StatusTabPanel, 'push');
            app.Inverter_button.ButtonPushedFcn = createCallbackFcn(app, @Inverter_buttonButtonPushed, true);
            app.Inverter_button.Text = 'Connect';
            app.InverterLabel = uilabel(app.StatusTabPanel);
            app.InverterLabel.Text = 'Inverter';
            app.IPEditFieldLabel = uilabel(app.StatusTabPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.StatusTabPanel, 'text');
            app.IPEditField.ValueChangedFcn = createCallbackFcn(app, @IPEditFieldValueChanged, true);
            app.IPEditField.Value = '10.0.0.1';
            app.TestingFlowControlPanel = uipanel(app.MainTab);
            app.TestingFlowControlPanel.Title = 'Testing Flow Control';
            app.RunButton = uibutton(app.TestingFlowControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'playicon.png');
            app.RunButton.Text = 'Run';
            app.ReportingPanel = uipanel(app.MainTab);
            app.ReportingPanel.Title = 'Reporting';
            app.AddressEditFieldLabel = uilabel(app.ReportingPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.ReportingPanel, 'text');
            app.PDFCheckBox = uicheckbox(app.ReportingPanel);
            app.PDFCheckBox.ValueChangedFcn = createCallbackFcn(app, @PDFCheckBoxValueChanged, true);
            app.PDFCheckBox.Text = 'PDF';
            app.DOCXCheckBox = uicheckbox(app.ReportingPanel);
            app.DOCXCheckBox.ValueChangedFcn = createCallbackFcn(app, @DOCXCheckBoxValueChanged, true);
            app.DOCXCheckBox.Text = 'DOCX';
            app.BrowseButton = uibutton(app.ReportingPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.GenerateReportButton = uibutton(app.ReportingPanel, 'push');
            app.GenerateReportButton.Text = 'Generate Report';
            app.ActionLogsPanel = uipanel(app.MainTab);
            app.ActionLogsPanel.Title = 'Action Logs';
            app.LogScreen = uitextarea(app.ActionLogsPanel);
            app.LogScreen.ValueChangedFcn = createCallbackFcn(app, @LogScreenValueChanged, true);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AuthorLabel = uilabel(app.AboutTab);
            app.AuthorLabel.Text = 'Author';
            app.SajidAlisajidaliskyelectriccomLabel = uilabel(app.AboutTab);
            app.SajidAlisajidaliskyelectriccomLabel.Text = {'Sajid Ali'; 'sajid.ali@skyelectric.com'};
            app.DescriptionLabel = uilabel(app.AboutTab);
            app.DescriptionLabel.Text = 'Description';
            app.ThisapplicationisdevelopedfortestingULinverterLabel = uilabel(app.AboutTab);
            app.ThisapplicationisdevelopedfortestingULinverterLabel.Text = 'This application is developed for testing UL inverter.';
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = {'The UL_Testing_App  application is designed to host and run scripts that interface with various equipment. By using this application, you acknowledge and agree to the following terms:'; ''; 'User Responsibility: Users are solely responsible for writing, testing, and running scripts within the application. The creators, developers, and distributors of UL_Testing_App assume no responsibility for the content, functionality, or outcomes of any scripts created by users.'; ''; 'Risk of Damage: Improperly written or incorrectly sequenced scripts can cause serious damage to equipment, data, and other systems interfaced by the application. Users are advised to thoroughly review and test their scripts in a controlled environment before running them in a live setting.'; ''; 'No Liability: The creators, developers, and distributors of UL_Testing_App  will not be held liable for any direct, indirect, incidental, or consequential damages arising from the use of the application, including but not limited to equipment damage, data loss, financial losses, or personal injury.'; ''; 'Independence of Scripts: The scripts run on this application are created and executed independently by users. UL_Testing_App  does not monitor, verify, or endorse any user-generated scripts, and therefore disclaims any responsibility for their content and effects.'; ''; 'Safety Precautions: Users are encouraged to implement and follow safety protocols when using the application. This includes but is not limited to creating backups, running scripts in a sandbox environment, and consulting with qualified professionals when interfacing with complex or sensitive equipment.'; ''; 'By using UL_Testing_App , you agree to release, indemnify, and hold harmless its creators, developers, and distributors from any claims, damages, or liabilities resulting from the use of this application. If you do not agree with these terms, you should not use UL_Testing_App .'};
            app.DisclaimerLabel = uilabel(app.AboutTab);
            app.DisclaimerLabel.Text = 'Disclaimer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UL_Testing_App_thermal_derating

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end